classdef FrontEnd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CeliXUIFigure   matlab.ui.Figure
        NoteLabel       matlab.ui.control.Label
        ThirdLabel      matlab.ui.control.Label
        SecondLabel     matlab.ui.control.Label
        FirstLabel      matlab.ui.control.Label
        ResultsLabel    matlab.ui.control.Label
        CeliXLabel      matlab.ui.control.Label
        Image           matlab.ui.control.Image
        AddVideoButton  matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddVideoButton
        function AddVideoButtonPushed(app, event)
            % Resetare vizualizari
            app.AddVideoButton.Visible = "off";
            app.FirstLabel.Visible = "off";
            app.SecondLabel.Visible = "off";
            app.ThirdLabel.Visible = "off";
            app.NoteLabel.Visible = "off";
            app.ResultsLabel.Visible = "off";
            
            % Deschidere path
            movieFullFileName = fileOpen();
            if (movieFullFileName == "None")
                return;
            end
            
            % Deschidere film
            videoObject = VideoReader(movieFullFileName);
            
            % Determinarea numarului de imagini/frame-uri
            %numberOfFrames = videoObject.NumFrames;
            numberOfFrames = 100;
            app.UIAxes.Visible = "on";
            
            % Initializari
            meanGrayLevels = zeros(numberOfFrames, 1);
            meanRedLevels = zeros(numberOfFrames, 1);
            meanGreenLevels = zeros(numberOfFrames, 1);
            meanBlueLevels = zeros(numberOfFrames, 1);
            KernelLevels = zeros(numberOfFrames, 1);
            SobelLevels = zeros(numberOfFrames, 1);
            stdGrayLevel =  zeros(numberOfFrames, 1);
            numRegions = zeros(numberOfFrames, 1);
            entropyLevels = zeros(numberOfFrames, 1);
            numSmallRegions = zeros(numberOfFrames, 1);
            
            % Prelucrare video
            for frame = 1 : numberOfFrames
                thisFrame = read(videoObject, frame);
                imshow(thisFrame,'Parent',app.UIAxes');
                app.UIAxes.Title.String = "Frame " + frame + " of "+...
                    + numberOfFrames;
                drawnow
                
                % Imaginea Binara ce contine doar zona de interes
                newGrayImage = elementsRemover(thisFrame);
                
                % Obtinerea imaginii color fara text
                [rows, columns] = size(newGrayImage);
                for i=1:rows
                    for j=1:columns
                        if( newGrayImage(i,j) == 0)
                            thisFrame(i,j,:) = [0,0,0];
                        end
                    end
                end
                
                thisFrame = cropImage(newGrayImage,thisFrame,rows,columns);
                
                %Ajustarea pozei
                n=0.2;
                Idouble = im2double(thisFrame);
                avg = mean2(Idouble);
                sigma = std2(Idouble);
                editFrame = imadjust(thisFrame,[avg-n*sigma avg+n*sigma],[]);
                
                grayImage = rgb2gray(thisFrame);
                grayEditedImage = rgb2gray(editFrame);
                grayEditedImage = grayEditedImage > 200;
                kernel = [ -2.3 -1 -0.3; -1.4 7.8 -1.4;-0.3 -1 0]; % kernel generat de mine
                afterKernelImage = conv2(im2double(grayImage), kernel);
                afterKernelImage = (afterKernelImage > 0);
                sobelX = [ -1 0 1; -2 0 2;-1 0 1];
                sobelY = [ -1 -2 -1;0 0 0;1 2 1];
                aftersobelX = conv2(im2double(grayEditedImage), sobelX);
                aftersobelY = conv2(im2double(grayEditedImage), sobelY);
                aftersobelXY = sqrt( aftersobelX.^2 + aftersobelY.^2);
                
                
                KernelLevels(frame) = sum(afterKernelImage(:));
                SobelLevels(frame) = sum(aftersobelXY(:));
                stdGrayLevel(frame) = std(im2double(grayImage(:)));
                meanGrayLevels(frame) = mean(im2double(grayImage(:)));
                meanRedLevels(frame) = mean(im2double(reshape(thisFrame(:, :, 1),1,[])));
                meanGreenLevels(frame) = mean(im2double(reshape(thisFrame(:, :, 2),1,[])));
                meanBlueLevels(frame) = mean(im2double(reshape(thisFrame(:, :, 3),1,[])));
                entropyLevels(frame) = entropy(grayImage);
                
                aftersobelXY = aftersobelXY > 0;
                aftersobelXY = imclearborder(aftersobelXY);
                aftersobelXY = imfill(aftersobelXY, 'holes');
                labeledImage = bwlabel(aftersobelXY);
                
                % Determinam centrele regiunilor
                regions = regionprops(labeledImage, 'Area', 'Centroid');
                
                % Suprafetele regiunilor ordonate  descrescator
                allAreas = sort([regions.Area], 'descend');
                numBig = 0;
                numSmall = 0;
                for i = 1:length(allAreas)
                    if allAreas(i) > 1000
                        numBig = numBig + 1;
                    elseif allAreas(i) <1000 && allAreas(i) > 100
                        numSmall = numSmall +1;
                    end
                end
                numRegions(frame) = numBig;
                numSmallRegions(frame) = numSmall;
                
            end
            
            Names = ["KernelLevels","SobelLevels","stdGrayLevel","meanGrayLevels","meanRedLevels",...
                "meanGreenLevels","meanBlueLevels","entropyLevels","numRegions","numSmallRegions"];
            Data = [ KernelLevels, SobelLevels, stdGrayLevel, meanGrayLevels, meanRedLevels,...
                meanGreenLevels, meanBlueLevels, entropyLevels, numRegions, numSmallRegions];
            AnalizeMatrix = [Names;Data];
            T = data2Table_App(AnalizeMatrix);
            load('ML_Functions.mat');
            
            app.ResultsLabel.Visible="on";
            r1 = FineKNN.predictFcn(T);
            if(r1 == 'I')
                app.FirstLabel.Text='First test result is Celiac';
            else
                app.FirstLabel.Text='First test result is Healthy';
            end
            app.FirstLabel.Visible = "on";
            
            r2 = WeightedKNN.predictFcn(T);
            if(r2 == 'I')
                app.SecondLabel.Text='Second test result is Celiac';
            else
                app.SecondLabel.Text='Second test result is Healthy';
            end
            app.SecondLabel.Visible = "on";
            
            r3 = LinearSVM.predictFcn(T);
            if(r3 == 'I')
                app.ThirdLabel.Text='Third test result is Celiac';
            else
                app.ThirdLabel.Text='Third test result is Healthy';
            end
            app.ThirdLabel.Visible = "on";
            
            app.NoteLabel.Visible = "on";
            app.AddVideoButton.Visible = "on";


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CeliXUIFigure = uifigure('Visible', 'off');
            app.CeliXUIFigure.Name = 'CeliX';
            app.CeliXUIFigure.Icon = 'logo_lic.png';
            app.UIAxes = uiaxes(app.CeliXUIFigure);
            app.AddVideoButton = uibutton(app.CeliXUIFigure, 'push');
            app.AddVideoButton.ButtonPushedFcn = createCallbackFcn(app, @AddVideoButtonPushed, true);
            app.AddVideoButton.Icon = 'uploadLogo.png';
            app.AddVideoButton.Text = 'Add Video';
            app.Image = uiimage(app.CeliXUIFigure);
            app.Image.Tag = 'logo';
            app.Image.ImageSource = 'logo_lic.png';
            app.CeliXLabel = uilabel(app.CeliXUIFigure);
            app.CeliXLabel.Text = 'CeliX';
            app.ResultsLabel = uilabel(app.CeliXUIFigure);
            app.ResultsLabel.Visible = 'off';
            app.ResultsLabel.Text = 'The results are:';
            app.FirstLabel = uilabel(app.CeliXUIFigure);
            app.FirstLabel.Visible = 'off';
            app.FirstLabel.Text = 'First test result: ';
            app.SecondLabel = uilabel(app.CeliXUIFigure);
            app.SecondLabel.Visible = 'off';
            app.SecondLabel.Text = 'Second test result:';
            app.ThirdLabel = uilabel(app.CeliXUIFigure);
            app.ThirdLabel.Visible = 'off';
            app.ThirdLabel.Text = 'Third test result:';
            app.NoteLabel = uilabel(app.CeliXUIFigure);
            app.NoteLabel.Visible = 'off';
            app.NoteLabel.Text = '*If one of the tests is celiac, please make further investigation.';
            % Show the figure after all components are created
            app.CeliXUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FrontEnd

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CeliXUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CeliXUIFigure)
        end
    end
end